classdef fc_trajectories < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        LoadDrawButton               matlab.ui.control.Button
        SubjectIDsListBoxLabel       matlab.ui.control.Label
        SubjectIDsListBox            matlab.ui.control.ListBox
        SelectScanButtonGroup        matlab.ui.container.ButtonGroup
        LR_REST1Button               matlab.ui.control.RadioButton
        RL_REST1Button               matlab.ui.control.RadioButton
        windowszSpinnerLabel         matlab.ui.control.Label
        windowszSpinner              matlab.ui.control.Spinner
        movesizeSpinnerLabel         matlab.ui.control.Label
        movesizeSpinner              matlab.ui.control.Spinner
        AtlasButtonGroup             matlab.ui.container.ButtonGroup
        desikanButton                matlab.ui.control.RadioButton
        destrieuxButton              matlab.ui.control.RadioButton
        BrainAreasButtonGroup        matlab.ui.container.ButtonGroup
        corticalButton               matlab.ui.control.RadioButton
        corticalsubcortButton        matlab.ui.control.RadioButton
        WindowSliderLabel            matlab.ui.control.Label
        WindowSlider                 matlab.ui.control.Slider
        PlaySwitch                   matlab.ui.control.Switch
        BackButton                   matlab.ui.control.Button
        NextButton                   matlab.ui.control.Button
        JumpSpinnerLabel             matlab.ui.control.Label
        JumpSpinner                  matlab.ui.control.Spinner
        SpeedsSpinnerLabel           matlab.ui.control.Label
        SpeedsSpinner                matlab.ui.control.Spinner
        Panel                        matlab.ui.container.Panel
        windowsLabel                 matlab.ui.control.Label
        NumWindowPlotSpinner         matlab.ui.control.Spinner
        GSOButtonGroup               matlab.ui.container.ButtonGroup
        AButton                      matlab.ui.control.RadioButton
        LButton                      matlab.ui.control.RadioButton
        A_normButton_2               matlab.ui.control.RadioButton
        L_normButton                 matlab.ui.control.RadioButton
        NormFreqReprButtonGroup      matlab.ui.container.ButtonGroup
        L2NormButton                 matlab.ui.control.RadioButton
        RawButton                    matlab.ui.control.RadioButton
        xbreaksSpinnerLabel          matlab.ui.control.Label
        xbreaksSpinner               matlab.ui.control.Spinner
        WhichFCButtonGroup           matlab.ui.container.ButtonGroup
        CorrButton                   matlab.ui.control.RadioButton
        CovButton                    matlab.ui.control.RadioButton
        fcscolorsSpinnerLabel        matlab.ui.control.Label
        fcscolorsSpinner             matlab.ui.control.Spinner
        ScalarsDropDownLabel         matlab.ui.control.Label
        ScalarsDropDown              matlab.ui.control.DropDown
        IncludedFreqsEditFieldLabel  matlab.ui.control.Label
        FilterFreqs                  matlab.ui.control.EditField
    end

    
    properties (Access = public)
        raw_hcp_datafolder = '/Volumes/Elements/brain_data';
        path2repo = '~/Documents/MATLAB/brain_data_preprocess'; %CHANGE THIS
        colormap_file = -1; %loaded later
        
        scan_dir = -1;
        
        %raw data
        last_dtseries = -1;
        eigvals = -1;
        
        
        %computed data
        num_windows = -1; %some arbitraty pos number for init
        signal_windows = -1;
        ave_signal_windows = -1;
        ave_signal_windows_freq_reprs_raw = -1;
        ave_signal_windows_freq_reprs_normed = -1;
        
        
        corrs = -1;
        covs = -1;
        
        
        y_min=-1;
        y_max=-1;
        plot_eigs = -1;
        eig_labels = -1;
        eig_idxs = -1;
        
        
        %parameters
        cols = 2;
        plot_timer = timer("BusyMode", 'queue', "ExecutionMode",'fixedSpacing',...
            'Name','plot_timer');
        subcortical_first = true;
        
        %private vars
        tile_plot = -1; %tiledlayout
        tiled_axes=-1;  %array of axes
        low_index = 1;
        high_index = -1;
        change_axes = true; %set this to one when we change a parameter that requires a refresh of the plots
        
        patient_list
        last_used = struct('subject', -1, 'path2fmri', ' ', ...
            'windowsize', -1, 'movesize', -1, 'include_subcortical',10);

        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath(app.path2repo)); %recursively adds all repo files/folders
            
            %load subject_list
            patient_sets = load('fc_and_sc_sets.mat');
            both_fcs_and_sc = patient_sets.exist_both_fc_and_sc;
            app.patient_list = both_fcs_and_sc;
            clear both_fcs_and_sc patient_sets
            
            items = cell(1,length(app.patient_list));
            for i = 1:length(app.patient_list)
                items{i} = num2str(app.patient_list(i));
            end
            % load patient list into ListBox
            app.SubjectIDsListBox.Items = items;
            
            app.colormap_file = load("viz_fcs/correlations_colormap.mat");
            app.plot_timer.TimerFcn = {@fc_traj_plot_callback,app};
            stop(app.plot_timer);
            
            app.NumWindowPlotSpinner.Enable='off';
        end

        % Button pushed function: LoadDrawButton
        function LoadDrawButtonPushed(app, event)
            %prevent user reclick until done
            app.LoadDrawButton.Enable='off';
            stop(app.plot_timer);
            app.change_axes = true;
            app.WindowSlider.Value = 1;
            drawnow;
            
            % load relevant fc file and sc scan
            
            subject = app.SubjectIDsListBox.Value;
            atlas   = app.AtlasButtonGroup.SelectedObject.Text;
            scan    = app.SelectScanButtonGroup.SelectedObject.Text;
            include_subcortical_text = app.BrainAreasButtonGroup.SelectedObject.Text;
            windowsize = int64(app.windowszSpinner.Value);
            movesize = int64(app.movesizeSpinner.Value);
            
            if contains(include_subcortical_text, "sub")
                include_subcortical = true;
            else
                include_subcortical = false;
            end
            
            if contains(scan, "REST1")
                tasktype ='rfMRI_REST1';
            elseif contains(scan,"REST2")
                tasktype ='rfMRI_REST2';
            end
            
            
            if atlas=="desikan"
                chosen_roi = load('data/desikan_roi_zhengwu.mat', 'roi').roi;
            elseif atlas=="destrieux"
                chosen_roi = load('data/destrieux_roi_zhengwu.mat', 'roi').roi;
            else
                error('unrecognized atlas %s', atlas);
            end
            
            
            if contains(scan, "LR")
                app.scan_dir = 'LR';
                path2fmri = [app.raw_hcp_datafolder '/' subject '/' tasktype '_LR_Atlas_hp2000_clean.dtseries.nii'];
            elseif contains(scan, "RL")
                app.scan_dir = 'RL';
                path2fmri = [app.raw_hcp_datafolder '/' subject '/' tasktype '_RL_Atlas_hp2000_clean.dtseries.nii'];
            end
            
            
            %if SAME DATA FILE, DONT RELOAD
            if strcmp(app.last_used.path2fmri, path2fmri)==1
                current_dtseries = app.last_dtseries;
            else
                %app.UIFigure.t
                loading_txt = sprintf('Loading %s, task %s, scan direction: %s...', subject, tasktype, scan);
                app.Panel.Title = loading_txt; 
                
                %cached patients for quick prototyping
                [current_dtseries] = process_fmri(atlas, path2fmri, subject, app.raw_hcp_datafolder, chosen_roi);
                app.last_dtseries  = current_dtseries;
                app.Panel.Title = strcat(loading_txt,'Done!!');
            end
            
            
            %remove subcortical if needed
            [num_rois,~] = size(current_dtseries);
            if include_subcortical==0
                roi_idxs = (20:num_rois); %subcortical are first. See README
            else
                roi_idxs = (1:num_rois);
            end
            
            dtseries = current_dtseries(roi_idxs,:);


            %% freq filtering
            [dtseries_freq, GFT, ~] = apply_GFT(dtseries, subject, atlas, ...
                include_subcortical, app.GSOButtonGroup.SelectedObject.Text);
            
            [freq_intervals]     = parse_freq_range(app.FilterFreqs.Value);
            filter_dtseries_freq = freq_filtering(dtseries_freq,freq_intervals);
            
            iGFT = GFT';
            filter_dtseries = iGFT*filter_dtseries_freq;
            
            dtseries = filter_dtseries;
            %%


            %over *all* time points in scan
            mean_signal = mean(dtseries,2);
            dtseries_center = dtseries-mean_signal;
            
            %standardize variance? => z-score??
            %stdv_signal = std(dtseries,1,2); %1-> use N in std comp, 2=>across columns
            %dtseries_zscored = dtseries_center./stdv_signal; %ensure correct division!!
            
            
            % slide window along time series to create windows of signals
            [app.signal_windows] = windowed_signals(dtseries_center, windowsize, movesize);
            %compute fcs from these subsets of signals in these windows
            [app.covs]  = construct_fcs(app.signal_windows);
            [app.corrs] = apply_to_tensor_slices(@corrcov, app.covs);
            %construct mean signals from these subsets of signals
            col_mean    = @(x) mean(x,2);
            [app.ave_signal_windows] = apply_to_tensor_slices(col_mean, app.signal_windows);
 
            [~, app.num_windows] = size(app.ave_signal_windows);
            [app.ave_signal_windows_freq_reprs_raw, ~, eigvals_] = ...
                apply_GFT(app.ave_signal_windows, subject, atlas, ...
                include_subcortical, app.GSOButtonGroup.SelectedObject.Text);
            app.eigvals = diag(eigvals_);
  
            app.last_used.path2fmri = path2fmri;
            
            %different titles if subcortical included
            sub_txt = '';
            if include_subcortical==1
                sub_txt = '+Subcortical';
            end
            app.Panel.Title = sprintf('patient %s %s1. Windowsize %d, Movesize %d || Atlas %s || Normed Freq?: %s || GSO: %s || Cortical%s', subject, app.scan_dir, windowsize, movesize, atlas, app.NormFreqReprButtonGroup.SelectedObject.Text, app.GSOButtonGroup.SelectedObject.Text, sub_txt);
            
            
            %if this is the first run, init idxs
            if (app.tile_plot== -1) || (app.high_index > app.num_windows)
                app.low_index  = 1;
                app.high_index = int64(app.NumWindowPlotSpinner.Value);
            end


            %plotting
            sig_digs = 4;
            eig_label_sparse_middle_jumps = 7;
            [app.eig_labels, app.eig_idxs] = create_eig_labels(app.eigvals, sig_digs, eig_label_sparse_middle_jumps);
            plot_grid(app)
            %for faster plotting, we keep same axes and simply update them
            %with new data. Only replace axes when we load new data. This
            %is handled internally of plot_grid() with the change_axes flag
            app.change_axes = false;
            
            %enable buttons
            app.BackButton.Enable='on';
            app.NextButton.Enable='on';
            app.PlaySwitch.Enable='on';
            app.LoadDrawButton.Enable='on';
            app.NumWindowPlotSpinner.Enable='on';
            app.WindowSlider.Limits= double([1,app.num_windows-(int64(app.NumWindowPlotSpinner.Value)-1)]);
            app.WindowSlider.Enable = 'on';
            drawnow;


        end

        % Selection changed function: SelectScanButtonGroup
        function SelectScanButtonGroupSelectionChanged(app, event)
            %selectedButton = app.SelectScanButtonGroup.SelectedObject;

        end

        % Value changed function: PlaySwitch
        function PlaySwitchValueChanged(app, event)
            value = app.PlaySwitch.Value;
            app.PlaySwitch.Enable='off';
            if strcmp(value,"Play")
                %disable buttons
                app.BackButton.Enable='off';
                app.NextButton.Enable='off';
                app.NumWindowPlotSpinner.Enable='off';
                app.WindowSlider.Enable = 'on';
                
                drawnow;
                
                start(app.plot_timer)
            else
                
                stop(app.plot_timer);
                app.plot_timer.Period = app.SpeedsSpinner.Value;
                
                %enable buttons
                app.BackButton.Enable='on';
                app.NextButton.Enable='on';
                app.NumWindowPlotSpinner.Enable='on';
                drawnow;
            end
            
            app.PlaySwitch.Enable='on';
                
                
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            jumpsize = int64(app.JumpSpinner.Value);
            % num_windows is the total # windows in fc trajectory, nothing to do
            % with plotting

            if app.high_index==app.num_windows % old high index at the end? Do nothing
                app.WindowSlider.Value=double(app.low_index);
                return
            elseif (app.high_index+jumpsize) > app.num_windows % will new high index out of range? Did something wrong
                app.low_index = app.num_windows-int64(app.NumWindowPlotSpinner.Value)+1;
                app.high_index = app.num_windows;
            else
                app.low_index  = app.low_index + jumpsize;
                app.high_index = app.high_index + jumpsize;
            end

            plot_grid(app)
            
            app.WindowSlider.Value=double(app.low_index);
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            jumpsize = int64(app.JumpSpinner.Value);
            % num_windows is the total # windows in fc trajectory, nothing to do
            % with plotting

            if app.low_index==0 % old low index at the end? Do nothing
                return
            elseif (app.low_index-jumpsize) < 0 % will new low index out of range?
                app.low_index  = app.num_windows-int64(app.NumWindowPlotSpinner.Value)+1; % Did something wrong
                app.high_index = app.num_windows;
            else
                app.low_index  = app.low_index  - jumpsize;
                app.high_index = app.high_index - jumpsize;
            end

            plot_grid(app)
            app.WindowSlider.Value=double(app.low_index);
        end

        % Value changed function: WindowSlider
        function WindowSliderValueChanged(app, event)
            app.low_index = int64(app.WindowSlider.Value);
            app.high_index = app.low_index + (int64(app.NumWindowPlotSpinner.Value)-1);
            plot_grid(app)
        end

        % Value changed function: NumWindowPlotSpinner
        function NumWindowPlotSpinnerValueChanged(app, event)
            value = app.NumWindowPlotSpinner.Value;
            app.WindowSlider.Limits=[1,app.num_windows-(value-1)];
            app.change_axes = true; %force plot_grid to create new axes
            app.high_index = app.low_index + (value-1);
            %app.tile_plot = tiledlayout(app.Panel, int64(app.NumWindowPlotSpinner.Value), app.cols,'TileSpacing','compact','Padding','none');
        end

        % Selection changed function: NormFreqReprButtonGroup
        function NormFreqReprButtonGroupSelectionChanged(app, event)
            %selectedButton = app.NormFreqReprButtonGroup.SelectedObject;
        end

        % Value changed function: fcscolorsSpinner
        function fcscolorsSpinnerValueChanged(app, event)
            percentile = app.fcscolorsSpinner.Value;
            which_fc = app.WhichFCButtonGroup.SelectedObject.Text;
            if strcmp(which_fc, 'Corr')
	            fcs = app.corrs;
            elseif strcmp(which_fc, 'Cov')
	            fcs = app.covs;
            else
	            error('Unrecognized FC selection %s\n', which_fc);
            end
            
            fc_axes = app.tiled_axes(:,1);
            [cl] = find_cov_clim(fcs, percentile);
            %set(fc_axes, 'ColorMap', app.colormap_file.colorMap);
            set(fc_axes,'CLim',[-cl cl]);
            %cbar = colorbar(fc_axes(int64(app.NumWindowPlotSpinner.Value)),'SouthOutside');
            drawnow;
        end

        % Value changed function: ScalarsDropDown
        function ScalarsDropDownValueChanged(app, event)
            value = app.ScalarsDropDown.Value;
            app.change_axes = true;
            plot_grid(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadDrawButton = uibutton(app.UIFigure, 'push');
            app.LoadDrawButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDrawButtonPushed, true);
            app.LoadDrawButton.Text = 'Load & Draw';
            app.SubjectIDsListBoxLabel = uilabel(app.UIFigure);
            app.SubjectIDsListBoxLabel.Text = 'Subject IDs';
            app.SubjectIDsListBox = uilistbox(app.UIFigure);
            app.SelectScanButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectScanButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectScanButtonGroupSelectionChanged, true);
            app.SelectScanButtonGroup.Title = 'Select Scan';
            app.LR_REST1Button = uiradiobutton(app.SelectScanButtonGroup);
            app.LR_REST1Button.Text = 'LR_REST1';
            app.LR_REST1Button.Value = true;
            app.RL_REST1Button = uiradiobutton(app.SelectScanButtonGroup);
            app.RL_REST1Button.Text = 'RL_REST1';
            app.windowszSpinnerLabel = uilabel(app.UIFigure);
            app.windowszSpinnerLabel.Text = 'windowsz';
            app.windowszSpinner = uispinner(app.UIFigure);
            app.windowszSpinner.Limits = [1 1200];
            app.windowszSpinner.Value = 30;
            app.movesizeSpinnerLabel = uilabel(app.UIFigure);
            app.movesizeSpinnerLabel.Text = 'movesize';
            app.movesizeSpinner = uispinner(app.UIFigure);
            app.movesizeSpinner.Limits = [1 1200];
            app.movesizeSpinner.Value = 10;
            app.AtlasButtonGroup = uibuttongroup(app.UIFigure);
            app.AtlasButtonGroup.Title = 'Atlas';
            app.desikanButton = uiradiobutton(app.AtlasButtonGroup);
            app.desikanButton.Text = 'desikan';
            app.desikanButton.Value = true;
            app.destrieuxButton = uiradiobutton(app.AtlasButtonGroup);
            app.destrieuxButton.Text = 'destrieux';
            app.BrainAreasButtonGroup = uibuttongroup(app.UIFigure);
            app.BrainAreasButtonGroup.Title = 'Brain Areas';
            app.corticalButton = uiradiobutton(app.BrainAreasButtonGroup);
            app.corticalButton.Text = 'cortical';
            app.corticalButton.Value = true;
            app.corticalsubcortButton = uiradiobutton(app.BrainAreasButtonGroup);
            app.corticalsubcortButton.Text = 'cortical + subcort';
            app.WindowSliderLabel = uilabel(app.UIFigure);
            app.WindowSliderLabel.Enable = 'off';
            app.WindowSliderLabel.Text = 'Window';
            app.WindowSlider = uislider(app.UIFigure);
            app.WindowSlider.Limits = [1 100];
            app.WindowSlider.ValueChangedFcn = createCallbackFcn(app, @WindowSliderValueChanged, true);
            app.WindowSlider.Enable = 'off';
            app.WindowSlider.Value = 1;
            app.PlaySwitch = uiswitch(app.UIFigure, 'slider');
            app.PlaySwitch.Items = {'Pause', 'Play'};
            app.PlaySwitch.ValueChangedFcn = createCallbackFcn(app, @PlaySwitchValueChanged, true);
            app.PlaySwitch.Enable = 'off';
            app.PlaySwitch.Value = 'Pause';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Enable = 'off';
            app.BackButton.Text = 'Back';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Enable = 'off';
            app.NextButton.Text = 'Next';
            app.JumpSpinnerLabel = uilabel(app.UIFigure);
            app.JumpSpinnerLabel.Text = 'Jump';
            app.JumpSpinner = uispinner(app.UIFigure);
            app.JumpSpinner.Limits = [1 100];
            app.JumpSpinner.Value = 1;
            app.SpeedsSpinnerLabel = uilabel(app.UIFigure);
            app.SpeedsSpinnerLabel.Text = 'Speed (s)';
            app.SpeedsSpinner = uispinner(app.UIFigure);
            app.SpeedsSpinner.Step = 0.5;
            app.SpeedsSpinner.Limits = [1 10000];
            app.SpeedsSpinner.Value = 1;
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.windowsLabel = uilabel(app.UIFigure);
            app.windowsLabel.Text = '# windows';
            app.NumWindowPlotSpinner = uispinner(app.UIFigure);
            app.NumWindowPlotSpinner.Limits = [1 10];
            app.NumWindowPlotSpinner.RoundFractionalValues = 'on';
            app.NumWindowPlotSpinner.ValueChangedFcn = createCallbackFcn(app, @NumWindowPlotSpinnerValueChanged, true);
            app.NumWindowPlotSpinner.Value = 3;
            app.GSOButtonGroup = uibuttongroup(app.UIFigure);
            app.GSOButtonGroup.Title = 'GSO';
            app.AButton = uiradiobutton(app.GSOButtonGroup);
            app.AButton.Text = 'A';
            app.LButton = uiradiobutton(app.GSOButtonGroup);
            app.LButton.Text = 'L';
            app.LButton.Value = true;
            app.A_normButton_2 = uiradiobutton(app.GSOButtonGroup);
            app.A_normButton_2.Text = 'A_norm';
            app.L_normButton = uiradiobutton(app.GSOButtonGroup);
            app.L_normButton.Text = 'L_norm';
            app.NormFreqReprButtonGroup = uibuttongroup(app.UIFigure);
            app.NormFreqReprButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NormFreqReprButtonGroupSelectionChanged, true);
            app.NormFreqReprButtonGroup.Title = 'Norm Freq Repr';
            app.L2NormButton = uiradiobutton(app.NormFreqReprButtonGroup);
            app.L2NormButton.Text = 'L2 Norm';
            app.L2NormButton.Value = true;
            app.RawButton = uiradiobutton(app.NormFreqReprButtonGroup);
            app.RawButton.Text = 'Raw';
            app.xbreaksSpinnerLabel = uilabel(app.UIFigure);
            app.xbreaksSpinnerLabel.Text = '# x breaks';
            app.xbreaksSpinner = uispinner(app.UIFigure);
            app.xbreaksSpinner.Limits = [0 10];
            app.xbreaksSpinner.RoundFractionalValues = 'on';
            app.xbreaksSpinner.Value = 1;
            app.WhichFCButtonGroup = uibuttongroup(app.UIFigure);
            app.WhichFCButtonGroup.Title = 'Which FC';
            app.CorrButton = uiradiobutton(app.WhichFCButtonGroup);
            app.CorrButton.Text = 'Corr';
            app.CorrButton.Value = true;
            app.CovButton = uiradiobutton(app.WhichFCButtonGroup);
            app.CovButton.Text = 'Cov';
            app.fcscolorsSpinnerLabel = uilabel(app.UIFigure);
            app.fcscolorsSpinnerLabel.Text = '% fcs colors';
            app.fcscolorsSpinner = uispinner(app.UIFigure);
            app.fcscolorsSpinner.Step = 0.1;
            app.fcscolorsSpinner.Limits = [0 100];
            app.fcscolorsSpinner.ValueChangedFcn = createCallbackFcn(app, @fcscolorsSpinnerValueChanged, true);
            app.fcscolorsSpinner.Value = 99.1;
            app.ScalarsDropDownLabel = uilabel(app.UIFigure);
            app.ScalarsDropDownLabel.Text = 'Scalars';
            app.ScalarsDropDown = uidropdown(app.UIFigure);
            app.ScalarsDropDown.Items = {'energy', 'cov_traj', 'Option 3', 'Option 4'};
            app.ScalarsDropDown.ValueChangedFcn = createCallbackFcn(app, @ScalarsDropDownValueChanged, true);
            app.ScalarsDropDown.Value = 'energy';
            app.IncludedFreqsEditFieldLabel = uilabel(app.UIFigure);
            app.IncludedFreqsEditFieldLabel.Text = 'Included Freqs';
            app.FilterFreqs = uieditfield(app.UIFigure, 'text');
            app.FilterFreqs.Value = '[1-68]';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fc_trajectories

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end