classdef fc_trajectories < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        LoadDrawButton              matlab.ui.control.Button
        SubjectIDsListBoxLabel      matlab.ui.control.Label
        SubjectIDsListBox           matlab.ui.control.ListBox
        windowszSpinnerLabel        matlab.ui.control.Label
        windowszSpinner             matlab.ui.control.Spinner
        movesizeSpinnerLabel        matlab.ui.control.Label
        movesizeSpinner             matlab.ui.control.Spinner
        WindowSliderLabel           matlab.ui.control.Label
        WindowSlider                matlab.ui.control.Slider
        PlaySwitch                  matlab.ui.control.Switch
        BackButton                  matlab.ui.control.Button
        NextButton                  matlab.ui.control.Button
        JumpSpinnerLabel            matlab.ui.control.Label
        JumpSpinner                 matlab.ui.control.Spinner
        SpeedsSpinnerLabel          matlab.ui.control.Label
        SpeedsSpinner               matlab.ui.control.Spinner
        Panel                       matlab.ui.container.Panel
        windowsLabel                matlab.ui.control.Label
        NumWindowPlotSpinner        matlab.ui.control.Spinner
        xbreaksSpinnerLabel         matlab.ui.control.Label
        xbreaksSpinner              matlab.ui.control.Spinner
        fcscolorsSpinnerLabel       matlab.ui.control.Label
        fcscolorsSpinner            matlab.ui.control.Spinner
        ScalarsDropDownLabel        matlab.ui.control.Label
        PlotScalars                 matlab.ui.control.DropDown
        GSODropDownLabel            matlab.ui.control.Label
        GSODropDown                 matlab.ui.control.DropDown
        AtlasDropDownLabel          matlab.ui.control.Label
        AtlasDropDown               matlab.ui.control.DropDown
        ScanDropDownLabel           matlab.ui.control.Label
        ScanDropDown                matlab.ui.control.DropDown
        ROIsDropDownLabel           matlab.ui.control.Label
        ROIsDropDown                matlab.ui.control.DropDown
        WdwFilterDropDownLabel      matlab.ui.control.Label
        wdw_filter                  matlab.ui.control.DropDown
        cutoffSpinnerLabel          matlab.ui.control.Label
        wdw_filter_cutoff           matlab.ui.control.Spinner
        rawfilterDropDownLabel      matlab.ui.control.Label
        raw_filter                  matlab.ui.control.DropDown
        cutoffSpinner_2Label        matlab.ui.control.Label
        raw_filter_cutoff           matlab.ui.control.Spinner
        FCDropDownLabel             matlab.ui.control.Label
        PlotFCs                     matlab.ui.control.DropDown
        raw_filter_use_percentile   matlab.ui.control.StateButton
        wdw_filter_use_percentile   matlab.ui.control.StateButton
        raw_filter_rm_from_fc_comp  matlab.ui.control.StateButton
        normalize_scalar_distrib_Button  matlab.ui.control.StateButton
        TopFreqsSpinnerLabel        matlab.ui.control.Label
        TopFreqsSpinner             matlab.ui.control.Spinner
        RmFreqsLabel                matlab.ui.control.Label
        RemoveFreqs                 matlab.ui.control.EditField
        VariationDropDownLabel      matlab.ui.control.Label
        VariationDropDown           matlab.ui.control.DropDown
        PlotDropDownLabel           matlab.ui.control.Label
        PlotDropDown                matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        raw_hcp_datafolder = '/Volumes/Elements/brain_data';
        path2repo = '~/Documents/MATLAB/brain_data_preprocess'; %CHANGE THIS
        colormap_file = -1; %loaded later
        
        scan_dir = -1;
        
        %raw data
        dtseries = -1; %straight from fmri
        eigvals = -1;
        total_variations = -1;
        zero_crossings = -1;
        roi_idxs = -1;
        average_node_value = -1;
        
        %%%%%%%%%%%%%%%%%%%%%%%
        %%computed data
        num_windows = -1; %some arbitraty pos number for init
        GFT = -1;
        
        %removed some based on threshold
        removed_dtseries_idxs = -1;
        filter_dtseries_raw_cutoff = -1;
        
        %frequency filtered optionally excluding preprocessed^. TIME signal
        %dtseries_raw_f = -1;  %remove subset of signals based on raw filter
        %dtseries_rawf_freqf = -1; %frequency filter remaining signals left
        dtseries_frequency_filtered = -1;
        %%%%%%%%%%%%%%%%%%%%%%%
        
        %signal_windows are taken AFTER
        % 1) mean centering
        % 2) (optional) raw preprocessing of dtseries
        % 3) frequency filtering
        signal_windows = -1;
        ave_signal_windows = -1;
        
        removed_window_idxs = -1;
        filter_wdw_raw_cutoff = -1;
        
        % taken directly from signal_windows
        corrs = -1;
        covs = -1;
        
        
        y_min=-1;
        y_max=-1;
        plot_eigs = -1;
        eig_labels = -1;
        eig_idxs = -1;
        
        
        %% parameters
        sig_digs = 4; %significant digits in xticks
        eig_label_sparse_middle_jumps = 10; %intersperse some xtick labels in large removed window
        plot_timer = timer("BusyMode", 'queue', "ExecutionMode",'fixedSpacing',...
            'Name','plot_timer');
        subcortical_first = true;
        
        %% private vars
        tile_plot = -1; %tiledlayout
        axes = struct();  %array of axes
        % tiledlayout is a grid of (num_windows_plot+1) x cols
        % this allows more precision as to how much space each component
        % takes up
        cols = 8;%2
        num_features_plot = 2; % {'fcs', 'freq_signals'};
        low_index = 1;
        high_index = -1;
        change_axes = true; %set this to one when we change a parameter that requires a refresh of the plots
        energy_freq_plot = struct(); %stores which intervals to use, what colors, etc...
        
        patient_list
        last_used = struct('subject', -1, 'path2fmri', ' ', ...
            'windowsize', -1, 'movesize', -1, 'include_subcortical',10);

        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath(app.path2repo)); %recursively adds all repo files/folders
            
            %load subject_list
            patient_sets = load('fc_and_sc_sets.mat');
            both_fcs_and_sc = patient_sets.exist_both_fc_and_sc;
            app.patient_list = both_fcs_and_sc;
            clear both_fcs_and_sc patient_sets
            
            items = cell(1,length(app.patient_list));
            for i = 1:length(app.patient_list)
                items{i} = num2str(app.patient_list(i));
            end
            % load patient list into ListBox
            app.SubjectIDsListBox.Items = items;
            
            app.colormap_file = load("viz_fcs/correlations_colormap.mat");
            app.plot_timer.TimerFcn = {@fc_traj_plot_callback,app};
            stop(app.plot_timer);
            
            app.NumWindowPlotSpinner.Enable='off';
        end

        % Button pushed function: LoadDrawButton
        function LoadDrawButtonPushed(app, event)
            
            %prevent user reclick until done
            app.LoadDrawButton.Enable='off';
            stop(app.plot_timer);
            app.change_axes = true;
            app.WindowSlider.Value = 1;
            drawnow;


            %%%%%%%%%%%%%%%%%%%
            %% construct unique path2fmri
            subject = app.SubjectIDsListBox.Value;
            atlas   = app.AtlasDropDown.Value;
            scan    = app.ScanDropDown.Value;
            include_subcortical_text = app.ROIsDropDown.Value;
            windowsize = int64(app.windowszSpinner.Value);
            movesize = int64(app.movesizeSpinner.Value);
            
            if contains(include_subcortical_text, "sub")
                include_subcortical = true;
            else
                include_subcortical = false;
            end
            
            if contains(scan, "REST1")
                tasktype ='rfMRI_REST1';
            elseif contains(scan,"REST2")
                tasktype ='rfMRI_REST2';
            end
            
            if atlas=="desikan"
                chosen_roi = load('data/desikan_roi_zhengwu.mat', 'roi').roi;
            elseif atlas=="destrieux"
                chosen_roi = load('data/destrieux_roi_zhengwu.mat', 'roi').roi;
            else
                error('unrecognized atlas %s', atlas);
            end
            
            if contains(scan, "LR")
                app.scan_dir = 'LR';
                path2fmri = [app.raw_hcp_datafolder '/' subject '/' tasktype '_LR_Atlas_hp2000_clean.dtseries.nii'];
            elseif contains(scan, "RL")
                app.scan_dir = 'RL';
                path2fmri = [app.raw_hcp_datafolder '/' subject '/' tasktype '_RL_Atlas_hp2000_clean.dtseries.nii'];
            end
            %%%%%%%%%%%%%%%%%%%
             
            %%%%%%%%%%%%%%%%%%%
            %if SAME DATA FILE, DONT RELOAD
            if ~strcmp(app.last_used.path2fmri, path2fmri)==1
                %app.UIFigure.t
                loading_txt     = sprintf('Loading %s, task %s, scan direction: %s...', subject, tasktype, scan);
                app.Panel.Title = loading_txt; 
                
                %cached patients for quick prototyping
                app.dtseries    = load_functional_dtseries(atlas, path2fmri, subject, app.raw_hcp_datafolder, chosen_roi);
                app.Panel.Title = strcat(loading_txt,'Done!!');
            end
            app.last_used.path2fmri = path2fmri; %save for next time
            %%%%%%%%%%%%%%%%%%%
            
            
            %%%%%%%%%%%%%%%%%%%
            %remove subcortical if needed
            [num_rois,~] = size(app.dtseries);
            if include_subcortical==0
                app.roi_idxs = (20:num_rois); %subcortical are first. See README
            else
                app.roi_idxs = (1:num_rois);
            end
            
            %remove mean bc all energy in first freq if not!
            % Subtract a scaled ones vector from all vector observations.
            % This removes (most of the )energy in constant frequency. 
            % GFT(x-u*1) = GFT(x) - GFT(u*1) = GFT(x) - u*GFT(1) 
            % = GFT(x) - u*[n/sqrt(N),0,0...,0]'
            sum_stat_file = load('data/fmri_desikan_summary_stats.mat');
            
            average_vector_ = mean([sum_stat_file.lrs.mean_vectors, sum_stat_file.rls.mean_vectors], 2);
            average_vector  = average_vector_(app.roi_idxs,:);
            app.average_node_value = mean(average_vector);
            dtseries_center = app.dtseries - app.average_node_value;% - mean(app.dtseries,2);
            num_rois = length(app.roi_idxs);
            num_obsvs = length(dtseries_center);
            %%%%%%%%%%%%%%%%%%%
            
            
            %%%%%%%%%%%%%%%%%%%
            [app.GFT, app.eigvals] = extract_GFT(subject, atlas, ...
                include_subcortical, app.GSODropDown.Value);
            iGFT = app.GFT';
            A = extract_sc(subject, atlas, include_subcortical);
            L = diag(sum(A,2))-A;
            app.total_variations = total_variation(iGFT, L);
            if any(app.total_variations<0)
                disp('error! negative total variations!!')
            end
            L_sign = diag(sum(sign(A),2))-sign(A);
            app.zero_crossings = (1/4)*total_variation(sign(iGFT), L_sign);
            %%%%%%%%%%%%%%%%%%%
            
            %%%%%%%%%%%%%%%%%%%
            %% RAW dtseries filtering
            %hardcode freq range for now
            low_freq_cutoffs  = floor(num_rois/3);
            med_freq_cutoffs  = 2*low_freq_cutoffs;
            low_freq_interval = [1, low_freq_cutoffs];
            med_freq_interval = [low_freq_cutoffs+1,  med_freq_cutoffs];
            high_freq_interval= [med_freq_cutoffs+1, num_rois];
            ranges = {low_freq_interval, med_freq_interval, high_freq_interval};

            raw_filter_params.threshold = app.raw_filter_cutoff.Value;
            raw_filter_params.name = app.raw_filter.Value;
            raw_filter_params.ranges = ranges; %simply dont use if not freq_distribution
            raw_filter_params.use_percentile = app.raw_filter_use_percentile.Value;
            
            % removed_dtseries_idxs - used to show which raw signals are
            %                         filtered
            [app.removed_dtseries_idxs, app.filter_dtseries_raw_cutoff] = ...
                samples_to_remove(dtseries_center(app.roi_idxs,:), app.GFT, ...
                raw_filter_params);
            kept_dtseries_idxs = setdiff(1:num_obsvs, app.removed_dtseries_idxs);
            %app.dtseries_rawf = app.dtseries(roi_idxs,kept_dtseries_idxs);
            %%%%%%%%%%%%%%%%%%%
            
            
            %%%%%%%%%%%%%%%%%%%
            %frequency filtered! BUT in time now, bc take iGFT!!!
            %{
            if app.TopFreqsSpinner.Value > 0
                error('dont use this');
                app.dtseries_frequency_filtered = ...
                iGFT*freq_filtering_top(app.GFT*dtseries_center(app.roi_idxs,:), app.TopFreqsSpinner.Value);
            end
            %}
            
            no_freq_filter = contains(app.RemoveFreqs.Value, 'None', "IgnoreCase", true);
            use_eig_order = contains(app.VariationDropDown.Value, "eig", "IgnoreCase", true);
            use_tv_l_order = contains(app.VariationDropDown.Value, "Lz", "IgnoreCase", true);
            use_tv_a_order = contains(app.VariationDropDown.Value, "Az", "IgnoreCase", true);
            use_zero_cross_order = contains(app.VariationDropDown.Value, "Zero-Crossings", "IgnoreCase", true);

            
            if no_freq_filter
                app.dtseries_frequency_filtered = dtseries_center(app.roi_idxs,:);
            else
                [cell_arr_intervals] = str_to_intervals(app.RemoveFreqs.Value);
                if use_eig_order
                    y = app.eigvals;
                elseif use_tv_l_order
                    y = app.total_variations;
                elseif use_tv_a_order
                    error('not implimented yet')
                elseif use_zero_cross_order
                    y = app.zero_crossings;
                end
                [idxs_to_remove]     = intervals_to_logical_vec(cell_arr_intervals, y);
                x_hat = app.GFT*dtseries_center(app.roi_idxs,:);
                x_hat(idxs_to_remove, :)=0.0;
                app.dtseries_frequency_filtered = iGFT*x_hat;
            end


            %%%%%%%%%%%%%%%%%%%
     
            
            %%%%%%%%%%%%%%%%%%%
            %should we use raw dtseries OR preprocessed dtseries when
            %computing windows?
            dtseries_fc_comp = app.dtseries_frequency_filtered;
            if app.raw_filter_rm_from_fc_comp.Value %remove from fc computation?
                dtseries_fc_comp = app.dtseries_frequency_filtered(:, kept_dtseries_idxs);
                if length(kept_dtseries_idxs)<30
                    fprintf('%s: only %d signals left after filtering!', subject, length(kept_dtseries_idxs));
                    
                end
            end
            %%%%%%%%%%%%%%%%%%%

            
            %%%%%%%%%%%%%%%%%%%
            %% Slide window along time series to create windows of signals
            app.signal_windows = windowed_signals(dtseries_fc_comp, windowsize, movesize);
            if length(size(app.signal_windows))==2
                app.covs = cov(app.signal_windows');
                app.corrs = corrcov(app.covs);
                app.ave_signal_windows = mean(app.signal_windows,2);
                app.num_windows = 1;
            else
                %compute fcs from these subsets of signals in these windows
                app.covs  = apply_to_tensor_slices(@(x) cov(x'), app.signal_windows);
                app.corrs = apply_to_tensor_slices(@corrcov, app.covs);
            
                %construct mean signals from these subsets of signals and
                %compute their frequency representation
                app.ave_signal_windows      = apply_to_tensor_slices(@(x) mean(x,2), app.signal_windows);
                [~,~, app.num_windows] = size(app.signal_windows);
            end
            
            if app.num_windows < app.NumWindowPlotSpinner.Value
                app.NumWindowPlotSpinner.Value = app.num_windows;
            end
            
            %%%%%%%%%%%%%%%%%%%
            
            
            %%%%%%%%%%%%%%%%%%%
            %% apply filtering to subsets (eigenvalue of cov, etc);

            fc_filter_params.name           = app.wdw_filter.Value;
            fc_filter_params.ranges         = ranges; %defined above
            fc_filter_params.threshold      = app.wdw_filter_cutoff.Value;
            fc_filter_params.use_percentile = app.wdw_filter_use_percentile.Value;
            [app.removed_window_idxs, app.filter_wdw_raw_cutoff] = preprocess_fcs(app.signal_windows, ...
                app.GFT, app.covs, fc_filter_params);
            %%%%%%%%%%%%%%%%%%%
            

            %if this is the first run, init idxs
            if (app.tile_plot== -1) || (app.high_index > app.num_windows)
                app.low_index  = 1;
                app.high_index = int64(app.NumWindowPlotSpinner.Value);
            end
            
            
            %%%%%%%%%%%%%%%%%%%
            %plotting
            %for plot_scalars
            app.energy_freq_plot.intervals = {low_freq_interval, med_freq_interval, high_freq_interval};
            app.energy_freq_plot.interval_labels = {'lpf', 'mpf', 'hpf'};
            app.energy_freq_plot.line_colors = {'r','m','c'};
   
            %[app.eig_labels, app.eig_idxs] = create_labels(app.eigvals, app.sig_digs, app.eig_label_sparse_middle_jumps);
            
            plot_grid(app)
            %for faster plotting, we keep same axes and simply update them
            %with new data. Only replace axes when we load new data. This
            %is handled internally of plot_grid() with the change_axes flag
            app.change_axes = false;
            %%%%%%%%%%%%%%%%%%%
            
            
            %different titles if subcortical included
            sub_txt = '';
            if include_subcortical==1
                sub_txt = '+Subcortical';
            end
            app.Panel.Title = sprintf('patient %s %s1. Windowsize %d, Movesize %d || Atlas %s || GSO: %s || Cortical%s', subject, app.scan_dir, windowsize, movesize, atlas, app.GSODropDown.Value, sub_txt);
            
            
            %enable buttons
            app.BackButton.Enable='on';
            app.NextButton.Enable='on';
            app.PlaySwitch.Enable='on';
            app.LoadDrawButton.Enable='on';
            app.NumWindowPlotSpinner.Enable='on';
            right_limit = app.num_windows-(app.NumWindowPlotSpinner.Value-1);
            if right_limit<=1
                right_limit=2;
            end
            app.WindowSlider.Limits= double([1,right_limit]);
            app.WindowSlider.Enable = 'on';
            drawnow;


        end

        % Callback function
        function SelectScanButtonGroupSelectionChanged(app, event)
            %selectedButton = app.SelectScanButtonGroup.SelectedObject;

        end

        % Value changed function: PlaySwitch
        function PlaySwitchValueChanged(app, event)
            value = app.PlaySwitch.Value;
            app.PlaySwitch.Enable='off';
            if strcmp(value,"Play")
                %disable buttons
                app.BackButton.Enable='off';
                app.NextButton.Enable='off';
                app.NumWindowPlotSpinner.Enable='off';
                app.WindowSlider.Enable = 'on';
                
                drawnow;
                
                start(app.plot_timer)
            else
                
                stop(app.plot_timer);
                app.plot_timer.Period = app.SpeedsSpinner.Value;
                
                %enable buttons
                app.BackButton.Enable='on';
                app.NextButton.Enable='on';
                app.NumWindowPlotSpinner.Enable='on';
                drawnow;
            end
            
            app.PlaySwitch.Enable='on';
                
                
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            jumpsize = int64(app.JumpSpinner.Value);
            % num_windows is the total # windows in fc trajectory, nothing to do
            % with plotting

            if app.high_index==app.num_windows % old high index at the end? Do nothing
                app.WindowSlider.Value=double(app.low_index);
                return
            elseif (app.high_index+jumpsize) > app.num_windows % will new high index out of range? Did something wrong
                app.low_index = app.num_windows-int64(app.NumWindowPlotSpinner.Value)+1;
                app.high_index = app.num_windows;
            else
                app.low_index  = app.low_index + jumpsize;
                app.high_index = app.high_index + jumpsize;
            end

            plot_grid(app)
            
            app.WindowSlider.Value=double(app.low_index);
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            jumpsize = int64(app.JumpSpinner.Value);
            % num_windows is the total # windows in fc trajectory, nothing to do
            % with plotting

            if app.low_index==0 % old low index at the end? Do nothing
                return
            elseif (app.low_index-jumpsize) < 0 % will new low index out of range?
                app.low_index  = app.num_windows-int64(app.NumWindowPlotSpinner.Value)+1; % Did something wrong
                app.high_index = app.num_windows;
            else
                app.low_index  = app.low_index  - jumpsize;
                app.high_index = app.high_index - jumpsize;
            end

            plot_grid(app)
            app.WindowSlider.Value=double(app.low_index);
        end

        % Value changed function: WindowSlider
        function WindowSliderValueChanged(app, event)
            app.low_index = int64(app.WindowSlider.Value);
            app.high_index = app.low_index + (int64(app.NumWindowPlotSpinner.Value)-1);
            plot_grid(app)
        end

        % Value changed function: NumWindowPlotSpinner
        function NumWindowPlotSpinnerValueChanged(app, event)
            value = app.NumWindowPlotSpinner.Value;
            if app.num_windows == 1
                app.WindowSlider.Limits=[1,2];
            else
                app.WindowSlider.Limits=[1,app.num_windows-(value-1)];
            end
            app.change_axes = true; %force plot_grid to create new axes
            app.high_index = app.low_index + (value-1);
            %app.tile_plot = tiledlayout(app.Panel, int64(app.NumWindowPlotSpinner.Value), app.cols,'TileSpacing','compact','Padding','none');
        end

        % Value changed function: fcscolorsSpinner
        function fcscolorsSpinnerValueChanged(app, event)
            %{
            percentile = app.fcscolorsSpinner.Value;
            which_fc = app.PlotFCs.Value;
            if strcmp(which_fc, 'Corr')
	            fcs = app.corrs;
            elseif strcmp(which_fc, 'Cov')
	            fcs = app.covs;
            else
	            error('Unrecognized FC selection %s\n', which_fc);
            end
            %}
            
            plot_grid(app);
            %fc_axes = app.tiled_axes(:,1);
            %[cl] = find_cov_clim(fcs, percentile);
            %set(fc_axes, 'ColorMap', app.colormap_file.colorMap);
            %set(fc_axes,'CLim',[-cl cl]);
            %cbar = colorbar(fc_axes(int64(app.NumWindowPlotSpinner.Value)),'SouthOutside');
            drawnow;
        end

        % Value changed function: PlotScalars
        function PlotScalarsValueChanged(app, event)
            value = app.PlotScalars.Value;
            app.change_axes = true;
            %plot_grid(app)
        end

        % Value changed function: PlotFCs
        function PlotFCsValueChanged(app, event)
            value = app.PlotFCs.Value; %% THIS HAS BEEN CHANGED TO PLOTSCALARS ABOVE^^
            app.change_axes = true;
            plot_grid(app)
        end

        % Value changed function: VariationDropDown
        function VariationDropDownValueChanged(app, event)
            value = app.VariationDropDown.Value;
            app.change_axes = true;
        end

        % Value changed function: RemoveFreqs
        function RemoveFreqsValueChanged(app, event)
            value = app.RemoveFreqs.Value;
            app.change_axes = true;
        end

        % Value changed function: TopFreqsSpinner
        function TopFreqsSpinnerValueChanged(app, event)
            value = app.TopFreqsSpinner.Value;
            app.change_axes = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadDrawButton = uibutton(app.UIFigure, 'push');
            app.LoadDrawButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDrawButtonPushed, true);
            app.LoadDrawButton.Text = 'Load & Draw';
            app.SubjectIDsListBoxLabel = uilabel(app.UIFigure);
            app.SubjectIDsListBoxLabel.Text = 'Subject IDs';
            app.SubjectIDsListBox = uilistbox(app.UIFigure);
            app.windowszSpinnerLabel = uilabel(app.UIFigure);
            app.windowszSpinnerLabel.Text = 'windowsz';
            app.windowszSpinner = uispinner(app.UIFigure);
            app.windowszSpinner.Limits = [1 1200];
            app.windowszSpinner.Value = 1100;
            app.movesizeSpinnerLabel = uilabel(app.UIFigure);
            app.movesizeSpinnerLabel.Text = 'movesize';
            app.movesizeSpinner = uispinner(app.UIFigure);
            app.movesizeSpinner.Limits = [1 1200];
            app.movesizeSpinner.Value = 20;
            app.WindowSliderLabel = uilabel(app.UIFigure);
            app.WindowSliderLabel.Enable = 'off';
            app.WindowSliderLabel.Text = 'Window';
            app.WindowSlider = uislider(app.UIFigure);
            app.WindowSlider.Limits = [1 100];
            app.WindowSlider.ValueChangedFcn = createCallbackFcn(app, @WindowSliderValueChanged, true);
            app.WindowSlider.Enable = 'off';
            app.WindowSlider.Value = 1;
            app.PlaySwitch = uiswitch(app.UIFigure, 'slider');
            app.PlaySwitch.Items = {'Pause', 'Play'};
            app.PlaySwitch.ValueChangedFcn = createCallbackFcn(app, @PlaySwitchValueChanged, true);
            app.PlaySwitch.Enable = 'off';
            app.PlaySwitch.Value = 'Pause';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Enable = 'off';
            app.BackButton.Text = 'Back';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Enable = 'off';
            app.NextButton.Text = 'Next';
            app.JumpSpinnerLabel = uilabel(app.UIFigure);
            app.JumpSpinnerLabel.Text = 'Jump';
            app.JumpSpinner = uispinner(app.UIFigure);
            app.JumpSpinner.Limits = [1 100];
            app.JumpSpinner.Value = 1;
            app.SpeedsSpinnerLabel = uilabel(app.UIFigure);
            app.SpeedsSpinnerLabel.Text = 'Speed (s)';
            app.SpeedsSpinner = uispinner(app.UIFigure);
            app.SpeedsSpinner.Step = 0.5;
            app.SpeedsSpinner.Limits = [1 10000];
            app.SpeedsSpinner.Value = 1;
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.windowsLabel = uilabel(app.UIFigure);
            app.windowsLabel.Text = '# windows';
            app.NumWindowPlotSpinner = uispinner(app.UIFigure);
            app.NumWindowPlotSpinner.Limits = [1 10];
            app.NumWindowPlotSpinner.RoundFractionalValues = 'on';
            app.NumWindowPlotSpinner.ValueChangedFcn = createCallbackFcn(app, @NumWindowPlotSpinnerValueChanged, true);
            app.NumWindowPlotSpinner.Value = 1;
            app.xbreaksSpinnerLabel = uilabel(app.UIFigure);
            app.xbreaksSpinnerLabel.Text = '# x breaks';
            app.xbreaksSpinner = uispinner(app.UIFigure);
            app.xbreaksSpinner.Limits = [0 10];
            app.xbreaksSpinner.RoundFractionalValues = 'on';
            app.fcscolorsSpinnerLabel = uilabel(app.UIFigure);
            app.fcscolorsSpinnerLabel.Text = '% fcs colors';
            app.fcscolorsSpinner = uispinner(app.UIFigure);
            app.fcscolorsSpinner.Step = 0.1;
            app.fcscolorsSpinner.Limits = [0 100];
            app.fcscolorsSpinner.ValueChangedFcn = createCallbackFcn(app, @fcscolorsSpinnerValueChanged, true);
            app.fcscolorsSpinner.Value = 99.1;
            app.ScalarsDropDownLabel = uilabel(app.UIFigure);
            app.ScalarsDropDownLabel.Text = 'Scalars';
            app.PlotScalars = uidropdown(app.UIFigure);
            app.PlotScalars.Items = {'signal_freq_distrib', 'wdw_ave_energy', 'fc_traj'};
            app.PlotScalars.ValueChangedFcn = createCallbackFcn(app, @PlotScalarsValueChanged, true);
            app.PlotScalars.Value = 'signal_freq_distrib';
            app.GSODropDownLabel = uilabel(app.UIFigure);
            app.GSODropDownLabel.Text = 'GSO';
            app.GSODropDown = uidropdown(app.UIFigure);
            app.GSODropDown.Items = {'A', 'L', 'A_norm', 'L_norm'};
            app.GSODropDown.Value = 'L';
            app.AtlasDropDownLabel = uilabel(app.UIFigure);
            app.AtlasDropDownLabel.Text = 'Atlas';
            app.AtlasDropDown = uidropdown(app.UIFigure);
            app.AtlasDropDown.Items = {'desikan', 'destrieux'};
            app.AtlasDropDown.Value = 'desikan';
            app.ScanDropDownLabel = uilabel(app.UIFigure);
            app.ScanDropDownLabel.Text = 'Scan';
            app.ScanDropDown = uidropdown(app.UIFigure);
            app.ScanDropDown.Items = {'LR_REST1', 'RL_REST1'};
            app.ScanDropDown.Value = 'LR_REST1';
            app.ROIsDropDownLabel = uilabel(app.UIFigure);
            app.ROIsDropDownLabel.Text = 'ROIs';
            app.ROIsDropDown = uidropdown(app.UIFigure);
            app.ROIsDropDown.Items = {'cortical', 'cortical + subcort'};
            app.ROIsDropDown.Value = 'cortical';
            app.WdwFilterDropDownLabel = uilabel(app.UIFigure);
            app.WdwFilterDropDownLabel.Text = 'Wdw Filter';
            app.wdw_filter = uidropdown(app.UIFigure);
            app.wdw_filter.Items = {'None', 'energy', 'freq_distribution', 'eig_1', 'eig_2'};
            app.wdw_filter.Value = 'freq_distribution';
            app.cutoffSpinnerLabel = uilabel(app.UIFigure);
            app.cutoffSpinnerLabel.Text = 'cutoff';
            app.wdw_filter_cutoff = uispinner(app.UIFigure);
            app.wdw_filter_cutoff.Step = 0.5;
            app.wdw_filter_cutoff.Value = 85;
            app.rawfilterDropDownLabel = uilabel(app.UIFigure);
            app.rawfilterDropDownLabel.Text = 'raw filter';
            app.raw_filter = uidropdown(app.UIFigure);
            app.raw_filter.Items = {'None', 'energy', 'freq_distribution'};
            app.raw_filter.Value = 'freq_distribution';
            app.cutoffSpinner_2Label = uilabel(app.UIFigure);
            app.cutoffSpinner_2Label.Text = 'cutoff';
            app.raw_filter_cutoff = uispinner(app.UIFigure);
            app.raw_filter_cutoff.Step = 0.5;
            app.raw_filter_cutoff.Value = 85;
            app.FCDropDownLabel = uilabel(app.UIFigure);
            app.FCDropDownLabel.Text = 'FC';
            app.PlotFCs = uidropdown(app.UIFigure);
            app.PlotFCs.Items = {'Corr', 'Cov', 'Option 3', 'Option 4'};
            app.PlotFCs.ValueChangedFcn = createCallbackFcn(app, @PlotFCsValueChanged, true);
            app.PlotFCs.Value = 'Cov';
            app.raw_filter_use_percentile = uibutton(app.UIFigure, 'state');
            app.raw_filter_use_percentile.Text = 'percentile?';
            app.wdw_filter_use_percentile = uibutton(app.UIFigure, 'state');
            app.wdw_filter_use_percentile.Text = 'percentile?';
            app.raw_filter_rm_from_fc_comp = uibutton(app.UIFigure, 'state');
            app.raw_filter_rm_from_fc_comp.Text = 'rm_fc_comp';
            app.normalize_scalar_distrib_Button = uibutton(app.UIFigure, 'state');
            app.normalize_scalar_distrib_Button.Text = 'normalize_scalar_distrib';
            app.TopFreqsSpinnerLabel = uilabel(app.UIFigure);
            app.TopFreqsSpinnerLabel.Text = 'Top Freqs';
            app.TopFreqsSpinner = uispinner(app.UIFigure);
            app.TopFreqsSpinner.Limits = [0 87];
            app.TopFreqsSpinner.ValueChangedFcn = createCallbackFcn(app, @TopFreqsSpinnerValueChanged, true);
            app.RmFreqsLabel = uilabel(app.UIFigure);
            app.RmFreqsLabel.Text = 'Rm Freqs';
            app.RemoveFreqs = uieditfield(app.UIFigure, 'text');
            app.RemoveFreqs.ValueChangedFcn = createCallbackFcn(app, @RemoveFreqsValueChanged, true);
            app.RemoveFreqs.Value = '[0.1,0.58]None';
            app.VariationDropDownLabel = uilabel(app.UIFigure);
            app.VariationDropDownLabel.Text = 'Variation';
            app.VariationDropDown = uidropdown(app.UIFigure);
            app.VariationDropDown.Items = {'Eigenvalues', 'TV: z''Lz', 'TV: |z-Az|', 'Zero-Crossings'};
            app.VariationDropDown.ValueChangedFcn = createCallbackFcn(app, @VariationDropDownValueChanged, true);
            app.VariationDropDown.Value = 'Eigenvalues';
            app.PlotDropDownLabel = uilabel(app.UIFigure);
            app.PlotDropDownLabel.Text = 'Plot';
            app.PlotDropDown = uidropdown(app.UIFigure);
            app.PlotDropDown.Items = {'Energy', 'Magnitude', 'Raw', 'CDF(en)'};
            app.PlotDropDown.Value = 'Energy';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fc_trajectories

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end